classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RemovenoiseButton  matlab.ui.control.Button
        AddnoiseButton     matlab.ui.control.Button
        loadimageButton    matlab.ui.control.Button
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadimageButton
        function loadimageButtonPushed(app, event)
           global readImage;
            [filename, pathname] = uigetfile('*,*', 'Load Image');
            filename=strcat(pathname,filename);
            readImage=imread(filename);
            imshow(readImage, 'Parent',app.UIAxes);  
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            global readImage;
            global noiseImage;
            noiseImage=readImage;
            noiseImage=imnoise(noiseImage,"salt & paper",0.3);
            imshow(noiseImage, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: RemovenoiseButton
        function RemovenoiseButtonPushed(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.loadimageButton = uibutton(app.UIFigure, 'push');
            app.loadimageButton.ButtonPushedFcn = createCallbackFcn(app, @loadimageButtonPushed, true);
            app.loadimageButton.Text = 'load image';
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.RemovenoiseButton = uibutton(app.UIFigure, 'push');
            app.RemovenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemovenoiseButtonPushed, true);
            app.RemovenoiseButton.Text = 'Remove noise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end